classdef winInstrument < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        DockModule              matlab.ui.container.GridLayout
        dockModule_Undock       matlab.ui.control.Image
        dockModule_Close        matlab.ui.control.Image
        Toolbar                 matlab.ui.container.GridLayout
        toolButton_edit         matlab.ui.control.Button
        toolButton_connectTest  matlab.ui.control.Button
        toolSeparator           matlab.ui.control.Image
        toolButton_export       matlab.ui.control.Image
        toolButton_open         matlab.ui.control.Image
        SubTabGroup             matlab.ui.container.TabGroup
        SubTab1                 matlab.ui.container.Tab
        SubGrid1                matlab.ui.container.GridLayout
        Tab2_PanelGrid          matlab.ui.container.GridLayout
        InstrumentPhoto         matlab.ui.control.Image
        InstrumentSpec          matlab.ui.control.Label
        InstrumentSpecLabel     matlab.ui.control.Label
        ParametersPanel         matlab.ui.container.Panel
        ParametersGrid          matlab.ui.container.GridLayout
        LocalHostPanel          matlab.ui.container.Panel
        LocalHostGrid           matlab.ui.container.GridLayout
        IPublic                 matlab.ui.control.EditField
        IPPublicLabel           matlab.ui.control.Label
        IPLocal                 matlab.ui.control.EditField
        IPLocalLabel            matlab.ui.control.Label
        LocalhostCheckBox       matlab.ui.control.CheckBox
        Timeout                 matlab.ui.control.NumericEditField
        TimeoutLabel            matlab.ui.control.Label
        BaudRate                matlab.ui.control.NumericEditField
        BaudRateLabel           matlab.ui.control.Label
        Port                    matlab.ui.control.EditField
        PortLabel               matlab.ui.control.Label
        IP                      matlab.ui.control.EditField
        IPLabel                 matlab.ui.control.Label
        Type                    matlab.ui.control.DropDown
        TypeLabel               matlab.ui.control.Label
        Description             matlab.ui.control.TextArea
        DescriptionLabel        matlab.ui.control.Label
        Name                    matlab.ui.control.DropDown
        NameLabel               matlab.ui.control.Label
        Family                  matlab.ui.control.DropDown
        FamilyLabel             matlab.ui.control.Label
        Status                  matlab.ui.control.DropDown
        StatusLabel             matlab.ui.control.Label
        ModePanel               matlab.ui.container.ButtonGroup
        ButtonGroupEdit         matlab.ui.control.RadioButton
        ButtonGroupView         matlab.ui.control.RadioButton
        ModePanelLabel          matlab.ui.control.Label
        Tab1_Grid               matlab.ui.container.GridLayout
        OperationArrowDown      matlab.ui.control.Image
        OperationArrowUp        matlab.ui.control.Image
        OperationDel            matlab.ui.control.Image
        OperationAdd            matlab.ui.control.Image
        Tree                    matlab.ui.container.Tree
        TreeNodeReceiver        matlab.ui.container.TreeNode
        TreeNodeGPS             matlab.ui.container.TreeNode
        TreeLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog

        receiverObj
        gpsObj
        instrumentList
        editedList
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)

                    otherwise
                        ipcMainJSEventsHandler(app.mainApp, event)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;
            
            switch tabIndex
                case 1        
                    elToModify = {app.InstrumentSpec};
                    elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
                    if ~isempty(elDataTag)
                        ui.TextView.startup(app.jsBackDoor, elToModify{1}, class(app));
                    end

                otherwise
                    % ...
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            app.receiverObj    = app.mainApp.receiverObj;
            app.gpsObj         = app.mainApp.gpsObj;
            app.instrumentList = [app.receiverObj.List; app.gpsObj.List];
            app.editedList     = app.instrumentList;
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.dockModule_Undock.Enable = 1;
            end
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            TreeBuilding(app, [])
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function TreeBuilding(app, idxSelectedNode)
            if ~isempty(app.TreeNodeReceiver.Children)
                delete(app.TreeNodeReceiver.Children)
            end
            
            if ~isempty(app.TreeNodeGPS.Children)
                delete(app.TreeNodeGPS.Children)
            end

            % Tree creation
            for ii = 1:height(app.editedList)
                switch app.editedList.Family{ii}
                    case 'Receiver'
                        Parent = app.TreeNodeReceiver;
                    case 'GPS'
                        Parent = app.TreeNodeGPS;                    
                    otherwise
                        continue
                end

                if ~ismember(app.editedList.Type{ii}, {'TCPIP Socket', 'TCP/UDP IP Socket', 'Serial'})
                    continue
                end

                nodeText = TreeBuilding_nodeText(app, ii);
                nodeTree = uitreenode(Parent, 'Text', nodeText, 'NodeData', ii, 'UserData', numel(Parent.Children)+1);

                if ~isempty(idxSelectedNode) && (ii == idxSelectedNode)
                    SelectedNode = nodeTree;
                end
            end            
            TreeBuilding_addStyle(app);

            % SelectedNode
            if exist('SelectedNode', 'var')
                app.Tree.SelectedNodes = SelectedNode;
            else
                app.Tree.SelectedNodes = app.Tree.Children(1).Children(1);
            end

            TreeSelectionChanged(app)
            expand(app.Tree, 'all')
        end

        %-----------------------------------------------------------------%
        function nodeText = TreeBuilding_nodeText(app, idx)
            Parameters = jsondecode(app.editedList.Parameters{idx});

            switch app.editedList.Type{idx}
                case 'Serial'
                    Socket = sprintf('%s', Parameters.Port);
                case {'TCPIP Socket', 'TCP/UDP IP Socket'}
                    Socket = sprintf('%s:%s', Parameters.IP, Parameters.Port);
            end

            nodeText = sprintf('%s - %s', app.editedList.Name{idx}, Socket);
        end

        %-----------------------------------------------------------------%
        function TreeBuilding_addStyle(app)
            if ~isempty(app.Tree.StyleConfigurations)
                removeStyle(app.Tree)
            end

            h = [allchild(app.TreeNodeReceiver); ...
                 allchild(app.TreeNodeGPS)];

            DisableNodes = [];
            for ii = 1:numel(h)
                idx = h(ii).NodeData;
                if ~app.editedList.Enable(idx)
                    DisableNodes = [DisableNodes, h(ii)];
                end
            end

            if ~isempty(DisableNodes)
                s = uistyle('FontColor', [.5 .5 .5]);
                addStyle(app.Tree, s, 'node', DisableNodes)
            end
        end

        %-----------------------------------------------------------------%
        function Layout_FamilyChanged(app, srcFcn)
            switch app.Family.Value
                case 'Receiver'
                    idx = strcmp(app.receiverObj.Config.Family, app.Family.Value);
                    app.Name.Items = unique(app.receiverObj.Config.Name(idx));

                case 'GPS'
                    idx = strcmp(app.gpsObj.Config.Family, app.Family.Value);
                    app.Name.Items = app.gpsObj.Config.Name(idx);
            end

            if strcmp(srcFcn, 'InstrumentParameterChanged')
                Layout_NameChanged(app, srcFcn)
            end
        end

        %-----------------------------------------------------------------%
        function Layout_NameChanged(app, srcFcn)
            switch app.Family.Value
                case 'Receiver'
                    idx = find(strcmp(app.receiverObj.Config.Name, app.Name.Value), 1);
                    app.Type.Items = app.receiverObj.Config.Definition{idx}.connection.types;

                case 'GPS'
                    idx = strcmp(app.gpsObj.Config.Name, app.Name.Value);
                    app.Type.Items = app.gpsObj.Config.connectType(idx);
            end

            if strcmp(srcFcn, 'InstrumentParameterChanged')
                Layout_TypeValueChanged(app)
            end
        end

        %-----------------------------------------------------------------%
        function Layout_TypeValueChanged(app)
            switch app.Type.Value
                case 'Serial'
                    app.ParametersGrid.ColumnWidth([1 3]) = {0, '1x'};
                    app.BaudRateLabel.Visible = 'on';
                    portValidation = contains(app.Port.Value, 'COM');

                otherwise
                    app.ParametersGrid.ColumnWidth([1 3]) = {110, 0};
                    app.BaudRateLabel.Visible = 'off';

                    if isempty(app.IP.Value)
                        app.IP.Value = '127.0.0.1';
                    end
                    portValidation = ~isnan(str2double(app.Port.Value));
            end

            if ~portValidation
                Layout_DefaultPort(app)
            end

            Layout_LocalhostCheckBox1(app)
            Layout_LocalhostCheckBox2(app)
        end

        %-----------------------------------------------------------------%
        function Layout_DefaultPort(app)
            switch app.Family.Value
                case 'Receiver'
                    idx = find(strcmp(app.receiverObj.Config.Name, app.Name.Value), 1);
                    app.Port.Value = num2str(app.receiverObj.Config.Definition{idx}.connection.defaultPort);

                case 'GPS'
                    idx = find(strcmp(app.gpsObj.Config.Name, app.Name.Value), 1);
                    app.Port.Value = app.gpsObj.Config.connectPort{idx};
            end
        end

        %-----------------------------------------------------------------%
        function Layout_LocalhostCheckBox1(app)
            app.LocalhostCheckBox.Enable = 0;

            if app.ButtonGroupEdit.Value && strcmp(app.Type.Value, "TCP/UDP IP Socket")
                app.LocalhostCheckBox.Enable = 1;
            end
        end

        %-----------------------------------------------------------------%
        function Layout_LocalhostCheckBox2(app)
            if app.LocalhostCheckBox.Value
                set(app.LocalHostGrid.Children, 'Enable', 1)

            else
                idx = app.Tree.SelectedNodes.NodeData;
                Parameters = jsondecode(app.editedList.Parameters{idx});

                if isfield(Parameters, 'Localhost_Enable')
                    Localhost_localIP  = Parameters.Localhost_localIP;
                    Localhost_publicIP = Parameters.Localhost_publicIP;
                else
                    Localhost_localIP  = '';
                    Localhost_publicIP = ''; 
                end

                set(app.IPLocal,  'Enable', 0, 'Value', Localhost_localIP)
                set(app.IPublic, 'Enable', 0, 'Value', Localhost_publicIP)
            end
        end

        %-----------------------------------------------------------------%
        function Layout_InstrumentSpecifications(app)            
            idx1 = app.Tree.SelectedNodes.NodeData;
            [htmlContent, imgSource] = util.HtmlTextGenerator.Instrument(app.receiverObj, app.gpsObj, app.editedList, idx1);

            app.InstrumentSpec.Text   = htmlContent;
            set(app.InstrumentPhoto, 'ImageSource', imgSource, 'Visible', 'on')
        end

        %-----------------------------------------------------------------%
        function ParameterUpdate(app)
            idx = app.Tree.SelectedNodes.NodeData;

            switch app.Type.Value
                case 'Serial'
                    app.editedList.Parameters{idx} = jsonencode(struct('Port',     app.Port.Value,     ...
                                                                       'BaudRate', app.BaudRate.Value, ...
                                                                       'Timeout',  app.Timeout.Value));

                case 'TCPIP Socket'
                    app.editedList.Parameters{idx} = jsonencode(struct('IP',       app.IP.Value,       ...
                                                                       'Port',     app.Port.Value,     ...
                                                                       'Timeout',  app.Timeout.Value));
                
                case 'TCP/UDP IP Socket'
                    if app.LocalhostCheckBox.Value; Localhost_Enable = 1;
                    else;                           Localhost_Enable = 0;
                    end

                    app.editedList.Parameters{idx} = jsonencode(struct('IP',       app.IP.Value,                ...
                                                                       'Port',     app.Port.Value,              ...
                                                                       'Timeout',  app.Timeout.Value,           ...
                                                                       'Localhost_Enable',   Localhost_Enable,  ...
                                                                       'Localhost_localIP',  app.IPLocal.Value, ...
                                                                       'Localhost_publicIP', app.IPublic.Value));
            end
        end

        %-----------------------------------------------------------------%
        function Flag = IPv4Validation(app, event)            
            switch event.Source
                case app.IP;       ipAddress = app.IP.Value;
                case app.IPLocal;  ipAddress = app.IPLocal.Value;
                case app.IPublic; ipAddress = app.IPublic.Value;
            end

            ipString = regexp(ipAddress, '\d*[.]{1}\d{1,3}[.]{1}\d{1,3}[.]{1}\d*', 'match');

            Flag = 0;
            if isempty(ipString)
                Flag = 1;
            else
                ipArray = cellfun(@(x) str2double(x), strsplit(char(ipString), '.'));
                if any(ipArray > 255) || any(isnan(ipArray))
                    Flag = 1;
                end                    
            end

            if Flag
                ui.Dialog(app.UIFigure, 'warning', 'Endereço IP inválido');

                switch event.Source
                    case app.IP;       app.IP.Value       = event.PreviousValue;
                    case app.IPLocal;  app.IPLocal.Value  = event.PreviousValue;
                    case app.IPublic; app.IPublic.Value = event.PreviousValue;
                end
            else
                if ~strcmp(ipAddress, char(ipString))
                    switch event.Source
                        case app.IP;       app.IP.Value       = char(ipString);
                        case app.IPLocal;  app.IPLocal.Value  = char(ipString);
                        case app.IPublic; app.IPublic.Value = char(ipString);
                    end
                end
            end
        end

        %-----------------------------------------------------------------%
        function Flag = PortValidation(app, event)
            Flag = 0;

            switch app.Type.Value
                case 'Serial'
                    portValidation = regexpi(app.Port.Value, 'COM\d+', 'match');
                    if isempty(portValidation)
                        Flag = 1;
                    end

                case {'TCPIP Socket', 'TCP/UDP IP Socket'}
                    portValidation = regexp(app.Port.Value, '\d+', 'match');
                    if isempty(portValidation)
                        Flag = 1;
                    else
                        if str2double(portValidation) > 65535
                            Flag = 1;
                        end
                    end
            end

            if Flag
                ui.Dialog(app.UIFigure, 'warning', 'Porta inválida');
                app.Port.Value = event.PreviousValue;
                
            else
                if ~strcmpi(app.Port.Value, char(portValidation))
                    app.Port.Value = upper(char(portValidation));
                end
            end
        end

        %-----------------------------------------------------------------%
        function [idx, msgError] = SelectionNodeValidation(app)
            try
                idx = app.Tree.SelectedNodes.NodeData;

                if isempty(idx)
                    switch app.Tree.SelectedNodes
                        case app.TreeNodeReceiver
                            app.Tree.SelectedNodes = app.TreeNodeReceiver.Children(1);
    
                        case app.TreeNodeGPS
                            if ~isempty(app.TreeNodeGPS.Children)
                                app.Tree.SelectedNodes = app.TreeNodeGPS.Children(1);
                            else
                                app.Tree.SelectedNodes = app.TreeNodeReceiver.Children(1);
                            end
                    end
                    idx = app.Tree.SelectedNodes.NodeData;
                end

                msgError = '';

            catch ME
                app.Tree.SelectedNodes = app.TreeNodeReceiver.Children(1);
                idx = app.Tree.SelectedNodes.NodeData;

                msgError = ME.message;
            end
        end

        %-----------------------------------------------------------------%
        function update(app)
            appName = class.Constants.appName;
            [~, programDataFolder] = appEngine.util.Path(appName, app.mainApp.rootFolder);
            saveNewFile(app, programDataFolder, false)

            % Após salva a nova versão de "InstrumentList.json", atualizam-se
            % as listas dos objetos HANDLE app.receiverObj e app.gpsObj.
            app.receiverObj.List = fileRead(app.receiverObj, app.mainApp.rootFolder);                
            gpsObjList = fileRead(app.gpsObj, app.mainApp.rootFolder);
            if ~isempty(gpsObjList)
                app.gpsObj.List = gpsObjList;
            else
                app.gpsObj.List(:,:) = [];
            end

            % Atualiza as propriedades (app.instrumentList e app.editedList
            % pois podem estar desatualizadas, no caso da edição excluir
            % todos os instrumentos, ou desabilitar todos):
            initializeAppProperties(app)
            TreeBuilding(app, [])

            % Fecha o módulo auxiliar "auxApp.winAddTask.mlapp", caso aberto.
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', 'TASK_ADD')
        end

        %-----------------------------------------------------------------%
        function saveNewFile(app, Folder, ShowAlert)
            fileList = table2struct(app.instrumentList);
            for ii = 1:numel(fileList)
                fileList(ii).Parameters = jsondecode(fileList(ii).Parameters);

                % Eliminar informação relacionada à localhost, caso não tenha 
                % sido preenchidos ao menos um dos campos de IP - "ip_local" 
                % ou "ip_público".

                if isfield(fileList(ii).Parameters, 'Localhost_Enable')
                    if fileList(ii).Parameters.Localhost_Enable  && isempty(fileList(ii).Parameters.Localhost_localIP) && isempty(fileList(ii).Parameters.Localhost_publicIP)
                        fileList(ii).Parameters = rmfield(fileList(ii).Parameters, {'Localhost_Enable', 'Localhost_localIP', 'Localhost_publicIP'});
                    end
                end
            end

            try
                writematrix(jsonencode(fileList, 'PrettyPrint', true), fullfile(Folder, 'InstrumentList.json'), 'FileType', 'text', 'QuoteStrings', 'none', 'WriteMode', 'overwrite', 'Encoding', 'UTF-8')
                if ShowAlert
                    ui.Dialog(app.UIFigure, 'warning', sprintf('Arquivo <b>InstrumentList.json</b> salvo na pasta "%s"', Folder));
                end
                
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            try
                appEngine.boot(app, app.Role, mainApp)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', 'INSTRUMENT')
            delete(app)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            
            % Caso o nó selecionado da árvore seja o "RECEPTOR" ou "GPS",
            % busca-se o seu primeiro filho, caso existente.
            idx = SelectionNodeValidation(app);

            % Ajuste dos itens que são listas suspensas (uidropdown)
            % porque no "MODO DE EDIÇÃO" todos os possíveis valores
            % estão disponíveis para escolha, enquanto que no "MODO DE
            % VISUALIZAÇÃO" ficará disponível apenas o valor indicado
            % no "TaskList.json".

            %---------------------------------------------------------%
            % ## MODO DE VISUALIZAÇÃO ##
            %---------------------------------------------------------%
            if app.ButtonGroupView.Value
                if app.editedList.Enable(idx); app.Status.Items = {'ON'};
                else;                          app.Status.Items = {'OFF'};
                end

                app.Family.Items = app.editedList.Family(idx);
                app.Name.Items   = app.editedList.Name(idx);
                app.Type.Items   = app.editedList.Type(idx);

            %---------------------------------------------------------%
            % ## MODO DE EDIÇÃO ##
            %---------------------------------------------------------%
            else
                if app.editedList.Enable(idx); app.Status.Value = 'ON';
                else;                          app.Status.Value = 'OFF';
                end

                app.Family.Value = app.editedList.Family{idx};
                Layout_FamilyChanged(app, '')
                
                app.Name.Value = app.editedList.Name{idx};
                Layout_NameChanged(app, 'InstrumentParameterChanged')

                app.Type.Value = app.editedList.Type{idx};
            end            

            % Ajustes nos outros campos (que não são listas suspensas), 
            % além de especificidades do campo "Fator integração" e dos
            % parâmetros relacionados à busca de emissões.

            app.Description.Value = app.editedList.Description{idx};
            app.LocalhostCheckBox.Value = 0;

            Parameters = jsondecode(app.editedList.Parameters{idx});
            switch app.Type.Value
                case 'Serial'
                    app.IP.Value       = '';
                    app.Port.Value     = Parameters.Port;
                    app.BaudRate.Value = Parameters.BaudRate;

                case {'TCPIP Socket', 'TCP/UDP IP Socket'}
                    app.IP.Value       = Parameters.IP;
                    app.Port.Value     = Parameters.Port;

                    if isfield(Parameters, 'Localhost_Enable')
                        app.LocalhostCheckBox.Value = Parameters.Localhost_Enable;
                        app.IPLocal.Value  = Parameters.Localhost_localIP;
                        app.IPublic.Value = Parameters.Localhost_publicIP;
                    else
                        app.LocalhostCheckBox.Value = 0;
                        app.IPLocal.Value           = '';
                        app.IPublic.Value          = '';
                    end
            end

            if isfield(Parameters, 'Timeout');  app.Timeout.Value = Parameters.Timeout;
            else;                               app.Timeout.Value = class.Constants.Timeout;
            end
            
            % Os últimos ajustes consistem na validação do campo "Port",
            % assim como atualização estética do instrumento.

            Layout_TypeValueChanged(app)
            Layout_InstrumentSpecifications(app)
            
        end

        % Selection changed function: ModePanel
        function ValueChanged_OperationMode(app, event)
            
            %-------------------------------------------------------------%
            % ## MODO DE VISUALIZAÇÃO ##
            %-------------------------------------------------------------%
            if app.ButtonGroupView.Value
                % Aspectos relacionados à indicação visual de que se trata 
                % do modo de visualização:

                set(findobj(app.Tab1_Grid, 'Type', 'uiimage'), Enable='off')
                app.Tab1_Grid.ColumnWidth{end} = 0;
                app.toolButton_edit.Visible  = 0;
                app.toolButton_open.Enable   = 'on';
                app.toolButton_export.Enable = 'on';                

                % Desabilita edição do conteúdo dos campos...

                app.Description.Editable = 'off';
                set(findobj(app.ParametersGrid, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                set(findobj(app.LocalHostGrid, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='off')
                app.LocalhostCheckBox.Enable = 0;

                set(app.Status, 'Items', {app.Status.Value})
                set(app.Family, 'Items', {app.Family.Value})
                set(app.Name,   'Items', {app.Name.Value})
                set(app.Type,   'Items', {app.Type.Value})

                % Essa última validação é essencial para desfazer alterações 
                % que não foram salvas. Ou seja, o usuário fez alterações
                % em app.taskList (que estavam armazenadas na sua cópia -
                % app.editedList) e não clicou no botão "Confirma edição".

                if ~isequal(app.instrumentList, app.editedList)
                    app.editedList = app.instrumentList;
                    TreeBuilding(app, [])
                end

            %-------------------------------------------------------------%
            % ## MODO DE EDIÇÃO ##
            %-------------------------------------------------------------%
            else
                % Aspectos relacionados à indicação visual de que se trata 
                % do modo de edição:

                set(app.Tab1_Grid.Children, Enable='on')
                app.Tab1_Grid.ColumnWidth{end} = 16;
                app.toolButton_edit.Visible  = 1;
                app.toolButton_open.Enable   = 'off';
                app.toolButton_export.Enable = 'off';

                % Habilita edição do conteúdo dos campos...

                app.Description.Editable = 'on';
                set(findobj(app.ParametersGrid, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                set(findobj(app.LocalHostGrid, 'Type', 'uinumericeditfield', '-or', 'Type', 'uieditfield'), Editable='on')
                app.LocalhostCheckBox.Enable = 1;

                set(app.Status, 'Items', {'ON', 'OFF'})
                set(app.Family, 'Items', {'Receiver', 'GPS'})
                Layout_FamilyChanged(app, 'InstrumentParameterChanged')
            end

        end

        % Value changed function: BaudRate, Description, Family, IP, 
        % ...and 8 other components
        function ValueChanged_Parameter(app, event)
            
            [idx, msgError] = SelectionNodeValidation(app);

            if ~isempty(msgError)
                TreeSelectionChanged(app)
                return
            end

            switch event.Source
                %---------------------------------------------------------%
                case app.Status
                    if app.Status.Value == "ON"
                        app.editedList.Enable(idx) = 1;
                    else
                        app.editedList.Enable(idx) = 0;
                    end

                %---------------------------------------------------------%
                case app.Family
                    Layout_FamilyChanged(app, 'InstrumentParameterChanged')
                    
                    app.editedList.Family{idx} = app.Family.Value;
                    app.editedList.Name{idx}   = app.Name.Value;
                    app.editedList.Type{idx}   = app.Type.Value;

                    if strcmp(app.Family.Value, 'Receiver')
                        Layout_DefaultPort(app)
                    end
                    
                    Layout_InstrumentSpecifications(app)
                    ParameterUpdate(app)
                    
                %---------------------------------------------------------%
                case app.Name
                    Layout_NameChanged(app, 'InstrumentParameterChanged')

                    app.editedList.Name{idx} = app.Name.Value;
                    app.editedList.Type{idx} = app.Type.Value;

                    if strcmp(app.Family.Value, 'Receiver')
                        Layout_DefaultPort(app)
                    end

                    Layout_InstrumentSpecifications(app)
                    ParameterUpdate(app)

                %---------------------------------------------------------%
                case app.Type
                    Layout_TypeValueChanged(app)                    

                    app.editedList.Type{idx} = app.Type.Value;
                    ParameterUpdate(app)

                %---------------------------------------------------------%
                case {app.Port, app.IP, app.IPLocal, app.IPublic}
                    Flag = 0;

                    if ~isempty(event.Source.Value)
                        if event.Source == app.Port
                            Flag = PortValidation(app, event);
                        else
                            Flag = IPv4Validation(app, event);
                        end
                    end

                    if ~Flag
                        ParameterUpdate(app)
                    end

                %---------------------------------------------------------%
                case app.BaudRate
                    ParameterUpdate(app)

                %---------------------------------------------------------%
                case app.Timeout
                    ParameterUpdate(app)

                %---------------------------------------------------------%
                case app.LocalhostCheckBox
                    Layout_LocalhostCheckBox2(app)
                    ParameterUpdate(app)

                %---------------------------------------------------------%
                case app.Description
                    app.editedList.Description{idx} = strjoin(app.Description.Value);
            end

            % Recriando a árvore...
            TreeBuilding(app, idx)
            
        end

        % Image clicked function: OperationAdd
        function ImageClicked_add(app, event)
            
            [idx, msgError] = SelectionNodeValidation(app);

            if isempty(msgError)
                idx_old = idx;
                idx_new = height(app.editedList) + 1;
    
                app.editedList(idx_new,:) = app.editedList(idx_old,:);
                TreeBuilding(app, idx_new)
            end

        end

        % Image clicked function: OperationDel
        function ImageClicked_del(app, event)
            
            [idx, msgError] = SelectionNodeValidation(app);

            if isempty(msgError)
                nodeParent = app.Tree.SelectedNodes.Parent;

                if nodeParent == app.TreeNodeReceiver    
                    if isscalar(nodeParent.Children)
                        return;
                    end
                end

                app.editedList(idx,:) = [];
                TreeBuilding(app, [])
            end

        end

        % Image clicked function: OperationArrowDown, OperationArrowUp
        function ImageClicked_UpDownArrows(app, event)
            
            [idx, msgError] = SelectionNodeValidation(app);

            if isempty(msgError)
                idx1_old = idx;
                idx2_old = app.Tree.SelectedNodes.UserData;
                Parent   = app.Tree.SelectedNodes.Parent;
    
                Flag     = 0;
                switch event.Source
                    case app.OperationArrowUp
                        if idx2_old > 1
                            idx1_new = Parent.Children(idx2_old-1).NodeData;
                            Flag     = 1;
                        end
    
                    case app.OperationArrowDown
                        if idx2_old < numel(Parent.Children)
                            idx1_new = Parent.Children(idx2_old+1).NodeData;
                            Flag     = 1;
                        end
                end
    
                if Flag
                    app.editedList([idx1_old, idx1_new],:) = flip(app.editedList([idx1_old, idx1_new],:));
                    TreeBuilding(app, idx1_new)
                end
            end

        end

        % Image clicked function: toolButton_open
        function toolButtonPushed_open(app, event)
            
            [File, Folder] = uigetfile({'*.json', '*.json'}, 'Selecione um arquivo', 'MultiSelect', 'off');
            figure(app.UIFigure)

            if File
                try
                    tempList = util.InstrumentIO.readInstrumentList(fullfile(Folder, File));

                    if ~isempty(tempList)
                        app.instrumentList = [app.instrumentList; tempList];
                        app.editedList     = app.instrumentList;
                        update(app)
                    end

                catch ME
                    ui.Dialog(app.UIFigure, 'error', getReport(ME));
                end
            end

        end

        % Image clicked function: toolButton_export
        function toolButtonPushed_export(app, event)
            
            Folder = uigetdir(app.mainApp.General.fileFolder.userPath, 'Escolha o diretório em que será salva a lista de instrumentos');
            figure(app.UIFigure)

            if Folder
                saveNewFile(app, Folder, true)
            end

        end

        % Button pushed function: toolButton_connectTest
        function toolButtonPushed_connectTest(app, event)
            
            app.progressDialog.Visible = 'visible';

            % O "idx1" se refere ao índice da tabela completa extraída de
            % "InstrumentList.json" (possivelmente já editada), incluindo 
            % receptores e GPSs.

            [idx, msgError] = SelectionNodeValidation(app);

            if isempty(msgError)
                idx1 = idx;
    
                switch app.Family.Value
                    case 'Receiver'
                        idx2 = find(strcmp(app.receiverObj.Config.Name, app.Name.Value), 1);
                        instrSelected = struct('Type',       app.Type.Value,                   ...
                                               'Tag',        app.receiverObj.Config.Tag{idx2}, ...
                                               'Parameters', jsondecode(app.editedList.Parameters{idx1}));
    
                        [~, notification] = testConnectivity(app.receiverObj, instrSelected, 1);
                        if ~isempty(notification)
                            ui.Dialog(app.UIFigure, notification.type, notification.message);
                        end
    
                    case 'GPS'
                        instrSelected = struct('Type',       app.Type.Value, ...
                                               'Parameters', jsondecode(app.editedList.Parameters{idx1}));
    
                        [~, ~, notification] = testConnectivity(app.gpsObj, instrSelected, 1);
                        if ~isempty(notification)
                            ui.Dialog(app.UIFigure, notification.type, notification.message);
                        end
                end
            end

            app.progressDialog.Visible = 'hidden';

        end

        % Button pushed function: toolButton_edit
        function toolButtonPushed_edit(app, event)
            
            % Finalizada a edição, avalia-se se algum parâmetro foi, de fato, 
            % alterado, salvando uma nova versão do arquivo "InstrumentList.json",
            % caso necessário.             
            if ~isequal(app.instrumentList, app.editedList)
                app.instrumentList = app.editedList;
                update(app)
            end
            
            app.ButtonGroupView.Value = 1;
            ValueChanged_OperationMode(app)

        end

        % Image clicked function: dockModule_Close, dockModule_Undock
        function DockModuleGroup_ButtonPushed(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.dockModule_Undock
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;
                    
                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];
                    
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.dockModule_Close
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SubTabGroup = uitabgroup(app.GridLayout);
            app.SubTab1 = uitab(app.SubTabGroup);
            app.SubTab1.Title = 'LISTA DE INSTRUMENTOS';
            app.SubGrid1 = uigridlayout(app.SubTab1);
            app.TreeLabel = uilabel(app.SubGrid1);
            app.TreeLabel.Text = 'INSTRUMENTOS';
            app.Tab1_Grid = uigridlayout(app.SubGrid1);
            app.Tree = uitree(app.Tab1_Grid);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.TreeNodeReceiver = uitreenode(app.Tree);
            app.TreeNodeReceiver.Text = 'RECEPTOR';
            app.TreeNodeGPS = uitreenode(app.Tree);
            app.TreeNodeGPS.Text = 'GPS';
            app.OperationAdd = uiimage(app.Tab1_Grid);
            app.OperationAdd.ImageClickedFcn = createCallbackFcn(app, @ImageClicked_add, true);
            app.OperationAdd.Enable = 'off';
            app.OperationAdd.Tooltip = {'Adiciona novo instrumento'};
            app.OperationAdd.ImageSource = 'addFileWithPlus_32.png';
            app.OperationDel = uiimage(app.Tab1_Grid);
            app.OperationDel.ImageClickedFcn = createCallbackFcn(app, @ImageClicked_del, true);
            app.OperationDel.Enable = 'off';
            app.OperationDel.Tooltip = {'Exclui instrumento selecionado'};
            app.OperationDel.ImageSource = 'Delete_32Red.png';
            app.OperationArrowUp = uiimage(app.Tab1_Grid);
            app.OperationArrowUp.ImageClickedFcn = createCallbackFcn(app, @ImageClicked_UpDownArrows, true);
            app.OperationArrowUp.Enable = 'off';
            app.OperationArrowUp.Tooltip = {'Troca ordem do instrumento selecionado'};
            app.OperationArrowUp.ImageSource = 'ArrowUp_32.png';
            app.OperationArrowDown = uiimage(app.Tab1_Grid);
            app.OperationArrowDown.ImageClickedFcn = createCallbackFcn(app, @ImageClicked_UpDownArrows, true);
            app.OperationArrowDown.Enable = 'off';
            app.OperationArrowDown.Tooltip = {'Troca ordem do instrumento selecionado'};
            app.OperationArrowDown.ImageSource = 'ArrowDown_32.png';
            app.ModePanelLabel = uilabel(app.SubGrid1);
            app.ModePanelLabel.Text = 'MODO';
            app.ModePanel = uibuttongroup(app.SubGrid1);
            app.ModePanel.SelectionChangedFcn = createCallbackFcn(app, @ValueChanged_OperationMode, true);
            app.ButtonGroupView = uiradiobutton(app.ModePanel);
            app.ButtonGroupView.Text = '<font style="color:#0000ff;">VISUALIZAR</font> lista';
            app.ButtonGroupView.Interpreter = 'html';
            app.ButtonGroupView.Value = true;
            app.ButtonGroupEdit = uiradiobutton(app.ModePanel);
            app.ButtonGroupEdit.Text = '<font style="color:#a2142f;"><b>EDITAR</b></font> lista';
            app.ButtonGroupEdit.Interpreter = 'html';
            app.Tab2_PanelGrid = uigridlayout(app.SubGrid1);
            app.StatusLabel = uilabel(app.Tab2_PanelGrid);
            app.StatusLabel.Text = 'ESTADO';
            app.Status = uidropdown(app.Tab2_PanelGrid);
            app.Status.Items = {'ON', 'OFF'};
            app.Status.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Status.Value = 'ON';
            app.FamilyLabel = uilabel(app.Tab2_PanelGrid);
            app.FamilyLabel.Text = 'FAMÍLIA';
            app.Family = uidropdown(app.Tab2_PanelGrid);
            app.Family.Items = {};
            app.Family.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Family.Value = {};
            app.NameLabel = uilabel(app.Tab2_PanelGrid);
            app.NameLabel.Text = 'FABRICANTE E MODELO';
            app.Name = uidropdown(app.Tab2_PanelGrid);
            app.Name.Items = {};
            app.Name.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Name.Value = {};
            app.DescriptionLabel = uilabel(app.Tab2_PanelGrid);
            app.DescriptionLabel.Text = 'DESCRIÇÃO';
            app.Description = uitextarea(app.Tab2_PanelGrid);
            app.Description.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Description.Editable = 'off';
            app.TypeLabel = uilabel(app.Tab2_PanelGrid);
            app.TypeLabel.Text = 'TIPO DE CONEXÃO';
            app.Type = uidropdown(app.Tab2_PanelGrid);
            app.Type.Items = {};
            app.Type.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Type.Value = {};
            app.ParametersPanel = uipanel(app.Tab2_PanelGrid);
            app.ParametersGrid = uigridlayout(app.ParametersPanel);
            app.IPLabel = uilabel(app.ParametersGrid);
            app.IPLabel.Text = 'IP:';
            app.IP = uieditfield(app.ParametersGrid, 'text');
            app.IP.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.IP.Editable = 'off';
            app.PortLabel = uilabel(app.ParametersGrid);
            app.PortLabel.Text = 'Porta:';
            app.Port = uieditfield(app.ParametersGrid, 'text');
            app.Port.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Port.Editable = 'off';
            app.BaudRateLabel = uilabel(app.ParametersGrid);
            app.BaudRateLabel.Visible = 'off';
            app.BaudRateLabel.Text = 'BaudRate:';
            app.BaudRate = uieditfield(app.ParametersGrid, 'numeric');
            app.BaudRate.Limits = [0 Inf];
            app.BaudRate.RoundFractionalValues = 'on';
            app.BaudRate.ValueDisplayFormat = '%.0f';
            app.BaudRate.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.BaudRate.Editable = 'off';
            app.BaudRate.Value = 9600;
            app.TimeoutLabel = uilabel(app.ParametersGrid);
            app.TimeoutLabel.Text = 'Timeout:';
            app.Timeout = uieditfield(app.ParametersGrid, 'numeric');
            app.Timeout.Limits = [1 20];
            app.Timeout.RoundFractionalValues = 'on';
            app.Timeout.ValueDisplayFormat = '%.0f';
            app.Timeout.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.Timeout.Editable = 'off';
            app.Timeout.Value = 5;
            app.LocalhostCheckBox = uicheckbox(app.ParametersGrid);
            app.LocalhostCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.LocalhostCheckBox.Enable = 'off';
            app.LocalhostCheckBox.Text = 'Localhost';
            app.LocalHostPanel = uipanel(app.ParametersGrid);
            app.LocalHostGrid = uigridlayout(app.LocalHostPanel);
            app.IPLocalLabel = uilabel(app.LocalHostGrid);
            app.IPLocalLabel.Text = 'IP local:';
            app.IPLocal = uieditfield(app.LocalHostGrid, 'text');
            app.IPLocal.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.IPLocal.Editable = 'off';
            app.IPLocal.Enable = 'off';
            app.IPPublicLabel = uilabel(app.LocalHostGrid);
            app.IPPublicLabel.Text = 'IP público:';
            app.IPublic = uieditfield(app.LocalHostGrid, 'text');
            app.IPublic.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_Parameter, true);
            app.IPublic.Editable = 'off';
            app.IPublic.Enable = 'off';
            app.InstrumentSpecLabel = uilabel(app.Tab2_PanelGrid);
            app.InstrumentSpecLabel.Text = 'ASPECTOS TÉCNICOS';
            app.InstrumentSpec = uilabel(app.Tab2_PanelGrid);
            app.InstrumentSpec.Interpreter = 'html';
            app.InstrumentSpec.Text = '';
            app.InstrumentPhoto = uiimage(app.Tab2_PanelGrid);
            app.InstrumentPhoto.Visible = 'off';
            app.Toolbar = uigridlayout(app.GridLayout);
            app.toolButton_open = uiimage(app.Toolbar);
            app.toolButton_open.ImageClickedFcn = createCallbackFcn(app, @toolButtonPushed_open, true);
            app.toolButton_open.Tooltip = {'Abre arquivo .json com lista de tarefas'};
            app.toolButton_open.ImageSource = 'Import_16.png';
            app.toolButton_export = uiimage(app.Toolbar);
            app.toolButton_export.ImageClickedFcn = createCallbackFcn(app, @toolButtonPushed_export, true);
            app.toolButton_export.Tooltip = {'Exporta arquivo .json com lista de tarefas'};
            app.toolButton_export.ImageSource = 'Export_16.png';
            app.toolSeparator = uiimage(app.Toolbar);
            app.toolSeparator.Enable = 'off';
            app.toolSeparator.ImageSource = 'LineV.svg';
            app.toolButton_connectTest = uibutton(app.Toolbar, 'push');
            app.toolButton_connectTest.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_connectTest, true);
            app.toolButton_connectTest.Icon = 'Connectivity_32.png';
            app.toolButton_connectTest.Tooltip = {'Teste de conectividade'};
            app.toolButton_connectTest.Text = '';
            app.toolButton_edit = uibutton(app.Toolbar, 'push');
            app.toolButton_edit.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_edit, true);
            app.toolButton_edit.Icon = 'Edit_32White.png';
            app.toolButton_edit.Visible = 'off';
            app.toolButton_edit.Text = 'Confirma edição';
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.dockModule_Close = uiimage(app.DockModule);
            app.dockModule_Close.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Close.Tag = 'DRIVETEST';
            app.dockModule_Close.Tooltip = {'Fecha módulo'};
            app.dockModule_Close.ImageSource = 'Delete_12SVG_white.svg';
            app.dockModule_Undock = uiimage(app.DockModule);
            app.dockModule_Undock.ImageClickedFcn = createCallbackFcn(app, @DockModuleGroup_ButtonPushed, true);
            app.dockModule_Undock.Tag = 'DRIVETEST';
            app.dockModule_Undock.Enable = 'off';
            app.dockModule_Undock.Tooltip = {'Reabre módulo em outra janela'};
            app.dockModule_Undock.ImageSource = 'Undock_18White.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winInstrument(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end