classdef winServer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        DockModule                 matlab.ui.container.GridLayout
        DockCloseButton            matlab.ui.control.Image
        DockUndockButton           matlab.ui.control.Image
        SubTabGroup                matlab.ui.container.TabGroup
        SubTab1                    matlab.ui.container.Tab
        SubGrid1                   matlab.ui.container.GridLayout
        communicationTable         matlab.ui.control.Table
        communicationTableRefresh  matlab.ui.control.Image
        communicationTableLabel    matlab.ui.control.Label
        serverInfo                 matlab.ui.control.TextArea
        serverInfoLabel            matlab.ui.control.Label
        Toolbar                    matlab.ui.container.GridLayout
        toolButton_edit            matlab.ui.control.Button
        toolLampLabel              matlab.ui.control.Label
        toolLamp                   matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        %-----------------------------------------------------------------%
        Role = 'secondaryApp'
        Context = 'SERVER'
    end


    properties (Access = public)
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        mainApp
        jsBackDoor
        progressDialog
    end


    methods (Access = public)
        %-----------------------------------------------------------------%
        function ipcSecondaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        appEngine.activate(app, app.Role)

                    otherwise
                        ipcMainJSEventsHandler(app.mainApp, event)
                end

            catch ME
                ui.Dialog(app.UIFigure, 'error', ME.message);
            end
        end

        %-----------------------------------------------------------------%
        function applyJSCustomizations(app, tabIndex)
            if app.SubTabGroup.UserData.isTabInitialized(tabIndex)
                return
            end
            app.SubTabGroup.UserData.isTabInitialized(tabIndex) = true;

            switch tabIndex
                case 1
                    appName = class(app);
                    elToModify = {
                        app.DockUndockButton;
                        app.DockCloseButton
                    };
                    ui.CustomizationBase.getElementsDataTag(elToModify);

                    try
                        sendEventToHTMLSource(app.jsBackDoor, 'initializeComponents', { ...
                            struct('appName', appName, 'dataTag', app.DockUndockButton.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Reabre módulo em outra janela')), ...
                            struct('appName', appName, 'dataTag', app.DockCloseButton.UserData.id, 'tooltip', struct('defaultPosition', 'bottom', 'textContent', 'Fecha módulo')) ...
                        });
                    catch
                    end

                otherwise
                    % ...
            end
        end

        %-----------------------------------------------------------------%
        function initializeAppProperties(app)
            % ...
        end

        %-----------------------------------------------------------------%
        function initializeUIComponents(app)
            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.DockUndockButton.Enable = 1;
            end
        end

        %-----------------------------------------------------------------%
        function applyInitialLayout(app)
            updateLayout(app)
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function updateLayout(app)
            if isempty(app.mainApp.tcpServer)
                app.serverInfo.Value = '';
                app.communicationTableRefresh.Visible = 0;

                app.toolLamp.Color = [.64 .08 .18];
                app.toolLampLabel.Text = 'Servidor não está em execução.';

                app.communicationTable.Data = table('Size', [0, 8],                                                                                    ...
                                               'VariableTypes', {'string', 'string', 'double', 'string', 'string', 'string', 'double', 'string'}, ...
                                               'VariableNames', {'Timestamp', 'ClientAddress', 'ClientPort', 'Message', 'ClientName', 'Request', 'NumBytesWritten', 'Status'});
                set(app.toolButton_edit, 'Text', 'Inicia servidor', 'Icon', 'play_32.png')

            elseif isempty(app.mainApp.tcpServer.Server)
                app.serverInfo.Value = '';
                app.communicationTableRefresh.Visible = 0;

                app.toolLamp.Color = [.5 .5 .5];
                app.toolLampLabel.Text = sprintf('Servidor ainda não está em execução, apesar do objeto "model.TcpServer" já ter sido criado. Será realizada uma nova tentativa para executá-lo a cada %d segundos.', class.Constants.tcpServerPeriod);

                app.communicationTable.Data = app.mainApp.tcpServer.Log;
                set(app.toolButton_edit, 'Text', 'Excluir objeto', 'Icon', 'Delete_32Red.png')

            else
                app.serverInfo.Value = util.HtmlTextGenerator.Server(app.mainApp.tcpServer);
                app.communicationTableRefresh.Visible = 1;

                app.toolLamp.Color = [.47 .67 .19];
                app.toolLampLabel.Text = sprintf('Servidor em execução desde %s.', char(app.mainApp.tcpServer.StartTime));

                app.communicationTable.Data = app.mainApp.tcpServer.Log;
                set(app.toolButton_edit, 'Text', 'Para servidor', 'Icon', 'stop_32.png')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)

            try
                appEngine.boot(app, app.Role, mainApp)
            catch ME
                ui.Dialog(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn', app.Context)
            delete(app)
            
        end

        % Image clicked function: DockCloseButton, DockUndockButton
        function onDockModuleGroupButtonClicked(app, event)
            
            [idx, auxAppTag, relatedButton] = getAppInfoFromHandle(app.mainApp.tabGroupController, app);

            switch event.Source
                case app.DockUndockButton
                    appGeneral = app.mainApp.General;
                    appGeneral.operationMode.Dock = false;

                    inputArguments = ipcMainMatlabCallsHandler(app.mainApp, app, 'dockButtonPushed', auxAppTag);
                    app.mainApp.tabGroupController.Components.appHandle{idx} = [];
                    
                    openModule(app.mainApp.tabGroupController, relatedButton, false, appGeneral, inputArguments{:})
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General, 'undock')
                    
                    delete(app)

                case app.DockCloseButton
                    closeModule(app.mainApp.tabGroupController, auxAppTag, app.mainApp.General)
            end

        end

        % Button pushed function: toolButton_edit
        function toolButtonPushed_edit(app, event)
            
            if isempty(app.mainApp.tcpServer)
                app.mainApp.tcpServer = model.TcpServer(app.mainApp);
            
            else
                stop(app.mainApp.tcpServer.Timer)
                delete(app.mainApp.tcpServer.Timer)
                delete(app.mainApp.tcpServer.Server)
                
                app.mainApp.tcpServer = [];
            end

            updateLayout(app)

        end

        % Image clicked function: communicationTableRefresh
        function communicationTableRefreshImageClicked(app, event)
            
            updateLayout(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appColeta';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Toolbar = uigridlayout(app.GridLayout);
            app.toolLamp = uilamp(app.Toolbar);
            app.toolLampLabel = uilabel(app.Toolbar);
            app.toolLampLabel.Text = 'Desconectado';
            app.toolButton_edit = uibutton(app.Toolbar, 'push');
            app.toolButton_edit.ButtonPushedFcn = createCallbackFcn(app, @toolButtonPushed_edit, true);
            app.toolButton_edit.Icon = 'play_32.png';
            app.toolButton_edit.Text = 'Inicia servidor';
            app.SubTabGroup = uitabgroup(app.GridLayout);
            app.SubTab1 = uitab(app.SubTabGroup);
            app.SubTab1.Title = 'SERVIDOR';
            app.SubGrid1 = uigridlayout(app.SubTab1);
            app.serverInfoLabel = uilabel(app.SubGrid1);
            app.serverInfoLabel.Text = 'CARACTERÍSTICAS:';
            app.serverInfo = uitextarea(app.SubGrid1);
            app.serverInfo.Editable = 'off';
            app.communicationTableLabel = uilabel(app.SubGrid1);
            app.communicationTableLabel.Text = 'COMUNICAÇÃO:';
            app.communicationTableRefresh = uiimage(app.SubGrid1);
            app.communicationTableRefresh.ImageClickedFcn = createCallbackFcn(app, @communicationTableRefreshImageClicked, true);
            app.communicationTableRefresh.Tooltip = {'Atualiza registro de comunicação'};
            app.communicationTableRefresh.ImageSource = 'Refresh_18.png';
            app.communicationTable = uitable(app.SubGrid1);
            app.communicationTable.ColumnName = {'INSTANTE'; 'IP'; 'PORTA'; 'MENSAGEM'; 'CLIENTE'; 'REQUISIÇÃO'; 'BYTES'; 'ESTADO'};
            app.communicationTable.RowName = {};
            app.DockModule = uigridlayout(app.GridLayout);
            app.DockModule.Visible = 'off';
            app.DockUndockButton = uiimage(app.DockModule);
            app.DockUndockButton.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.DockUndockButton.Enable = 'off';
            app.DockUndockButton.ImageSource = 'Undock_18White.png';
            app.DockCloseButton = uiimage(app.DockModule);
            app.DockCloseButton.ImageClickedFcn = createCallbackFcn(app, @onDockModuleGroupButtonClicked, true);
            app.DockCloseButton.ImageSource = 'Delete_12SVG_white.svg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winServer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end